classdef RocketTrajectoryOptimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes_Traj                  matlab.ui.control.UIAxes
        UIAxes_mass                  matlab.ui.control.UIAxes
        UIAxes_gamma                 matlab.ui.control.UIAxes
        UIAxes_velocity              matlab.ui.control.UIAxes
        OptimizeButton               matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        LandingTypeListBox           matlab.ui.control.ListBox
        ChooseVehicleDropDown        matlab.ui.control.DropDown
        MassRemainingEditFieldLabel  matlab.ui.control.Label
        MassRemainingEditField       matlab.ui.control.NumericEditField
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        RangeEditFieldLabel          matlab.ui.control.Label
        RangeEditField               matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel       matlab.ui.control.Label
        AltitudeEditField            matlab.ui.control.NumericEditField
        VelocityEditFieldLabel       matlab.ui.control.Label
        VelocityEditField            matlab.ui.control.NumericEditField
        LandingTypeButton            matlab.ui.control.Button
        VehicleTypeButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            landing_type= app.LandingTypeListBox.Value;
            if landing_type == "Unspecified Landing "
                data = optimum(app.ChooseVehicleDropDown.Value);
                time = data(1,:);
                x= data(2,:);
                y= data(3,:);
                v= data(4,:);
                gamma= data(5,:);
                m=data(6,:);
            
            
                app.MassRemainingEditField.Value= m(end);
                app.RangeEditField.Value=x(end);
                app.AltitudeEditField.Value=y(end);
                app.VelocityEditField.Value=v(end);
            
                ln1= plot(app.UIAxes_Traj,x(1:100),y(1:100),"--",x(100:200),y(100:200),"-")
                ln1(1).LineWidth = 2;
                ln1(2).LineWidth = 2;
                ln2=plot(app.UIAxes_gamma,time(1:100),gamma(1:100),"--",time(100:200),gamma(100:200),"-")
                ln2(1).LineWidth = 2;
                ln2(2).LineWidth = 2;
                ln3=plot(app.UIAxes_mass,time(1:100),m(1:100),"--",time(100:200),m(100:200),"-")
                ln3(1).LineWidth = 2;
                ln3(2).LineWidth = 2;
                lg=legend(app.UIAxes_mass,'Coasting Phase','Landing Phase','FontSize',12,'TextColor','white')
                ln4=plot(app.UIAxes_velocity,time(1:100),v(1:100),"--",time(100:200),v(100:200),"-")
                ln4(1).LineWidth = 2;
                ln4(2).LineWidth = 2;
            elseif landing_type == "Specified Landing"
                data= optim_sl(app.ChooseVehicleDropDown.Value);
                time = data(1,:);
                x= data(2,:);
                y= data(3,:);
                v= data(4,:);
                gamma= data(5,:);
                m=data(6,:);
            
            
                app.MassRemainingEditField.Value= m(end);
                app.RangeEditField.Value=x(end);
                app.AltitudeEditField.Value=y(end);
                app.VelocityEditField.Value=v(end);
            
                ln5=plot(app.UIAxes_Traj,x(1:100),y(1:100),"--",x(100:200),y(100:200),"-",x(200:300),y(200:300),":")
                ln5(1).LineWidth = 2;
                ln5(2).LineWidth = 2;
                ln5(3).LineWidth = 2;
                ln6=plot(app.UIAxes_gamma,time(1:100),gamma(1:100),"--",time(100:200),gamma(100:200),"-",time(200:300),gamma(200:300),":")
                ln6(1).LineWidth = 2;
                ln6(2).LineWidth = 2;
                ln6(3).LineWidth = 2;
                ln7=plot(app.UIAxes_mass,time(1:100),m(1:100),"--",time(100:200),m(100:200),"-",time(200:300),m(200:300),":")
                ln7(1).LineWidth = 2;
                ln7(2).LineWidth = 2;
                ln7(3).LineWidth = 2;
                lgd1=legend(app.UIAxes_mass,'Boast-Back Phase','Coasting Phase','Landing Phase','FontSize',12,'TextColor','white')
                ln8=plot(app.UIAxes_velocity,time(1:100),v(1:100),"--",time(100:200),v(100:200),"-",time(200:300),v(200:300),":")
                ln8(1).LineWidth = 2;
                ln8(2).LineWidth = 2;
                ln8(3).LineWidth = 2;
            elseif landing_type == "Return to Launch site"
                data= optimum_rls(app.ChooseVehicleDropDown.Value);
                time = data(1,:);
                x= data(2,:);
                y= data(3,:);
                v= data(4,:);
                gamma= data(5,:);
                m=data(6,:);
            
            
                app.MassRemainingEditField.Value= m(end);
                app.RangeEditField.Value=x(end);
                app.AltitudeEditField.Value=y(end);
                app.VelocityEditField.Value=v(end);
            
                l1=plot(app.UIAxes_Traj,x(1:100),y(1:100),"--",x(100:200),y(100:200),"-",x(200:300),y(200:300),":")
                l1(1).LineWidth = 2;
                l1(2).LineWidth = 2;
                l1(3).LineWidth = 2;
                l2=plot(app.UIAxes_gamma,time(1:100),gamma(1:100),"--",time(100:200),gamma(100:200),"-",time(200:300),gamma(200:300),":")
                l2(1).LineWidth = 2;
                l2(2).LineWidth = 2;
                l2(3).LineWidth = 2;
                l3=plot(app.UIAxes_mass,time(1:100),m(1:100),"--",time(100:200),m(100:200),"-",time(200:300),m(200:300),":")
                l3(1).LineWidth = 2;
                l3(2).LineWidth = 2;
                l3(3).LineWidth = 2;
                lgd=legend(app.UIAxes_mass,'Boast-Back Phase','Coasting Phase','Landing Phase','FontSize',12,'TextColor','white')
                l4=plot(app.UIAxes_velocity,time(1:100),v(1:100),"--",time(100:200),v(100:200),"-",time(200:300),v(200:300),":")
                l4(1).LineWidth = 2;
                l4(2).LineWidth = 2;
                l4(3).LineWidth = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Traj = uiaxes(app.UIFigure);
            title(app.UIAxes_Traj, '')
            xlabel(app.UIAxes_Traj, 'Downrange')
            ylabel(app.UIAxes_Traj, 'Altitude')
            app.UIAxes_mass = uiaxes(app.UIFigure);
            title(app.UIAxes_mass, '')
            xlabel(app.UIAxes_mass, 'time')
            ylabel(app.UIAxes_mass, 'Mass')
            app.UIAxes_mass.AlphaScale = 'log';
            app.UIAxes_gamma = uiaxes(app.UIFigure);
            title(app.UIAxes_gamma, '')
            xlabel(app.UIAxes_gamma, 'time')
            ylabel(app.UIAxes_gamma, 'Velocity')
            app.UIAxes_velocity = uiaxes(app.UIFigure);
            title(app.UIAxes_velocity, '')
            xlabel(app.UIAxes_velocity, 'time')
            ylabel(app.UIAxes_velocity, 'Gamma')
            app.OptimizeButton = uibutton(app.UIFigure, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '';
            app.LandingTypeListBox = uilistbox(app.UIFigure);
            app.LandingTypeListBox.Items = {'Unspecified Landing ', 'Specified Landing', 'Return to Launch site', ''};
            app.LandingTypeListBox.Value = 'Unspecified Landing ';
            app.ChooseVehicleDropDown = uidropdown(app.UIFigure);
            app.ChooseVehicleDropDown.Items = {'Falcon 9 ', 'Electron ', 'New Shepard'};
            app.ChooseVehicleDropDown.Value = 'Falcon 9 ';
            app.MassRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.MassRemainingEditFieldLabel.Text = 'Mass Remaining';
            app.MassRemainingEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassRemainingEditField.ValueDisplayFormat = '%.3f';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField.ValueDisplayFormat = '%.3f';
            app.AltitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AltitudeEditField.ValueDisplayFormat = '%.3f';
            app.VelocityEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.ValueDisplayFormat = '%.3f';
            app.LandingTypeButton = uibutton(app.UIFigure, 'push');
            app.LandingTypeButton.Text = 'Landing Type';
            app.VehicleTypeButton = uibutton(app.UIFigure, 'push');
            app.VehicleTypeButton.Text = 'Vehicle Type';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RocketTrajectoryOptimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end